classdef SampleGUIApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        btnLoadImage      matlab.ui.control.Button
        axesImage         matlab.ui.control.UIAxes
        axesOutputImage   matlab.ui.control.UIAxes
        EnterSESizeLabel  matlab.ui.control.Label
        editSESize        matlab.ui.control.NumericEditField
        btnOpenImage      matlab.ui.control.Button
        btnCloseImage     matlab.ui.control.Button
    end


    properties (Access = private)
        imgMatrix % Description
    end


    methods (Access = private)

        % Button pushed function: btnLoadImage
        function btnLoadImageButtonPushed(app, event)
            % Get the pathname and filename
            [File_Name, Path_Name] = uigetfile('*.tif','*.jpg');
            % Show the image in the GUI axes
            app.imgMatrix = imread([Path_Name,File_Name]);
            imshow(app.imgMatrix, 'Parent', app.axesImage);
        end

        % Button pushed function: btnOpenImage
        function btnOpenImageButtonPushed(app, event)
            SE = strel('square',app.editSESize.Value);
            openImg = imopen(app.imgMatrix, SE);
            imshow(openImg, 'Parent', app.axesOutputImage);
        end

        % Button pushed function: btnCloseImage
        function btnCloseImageButtonPushed(app, event)
            SE = strel('square',app.editSESize.Value);
            closedImg = imclose(app.imgMatrix, SE);
            imshow(closedImg, 'Parent', app.axesOutputImage);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.btnLoadImage = uibutton(app.UIFigure, 'push');
            app.btnLoadImage.ButtonPushedFcn = createCallbackFcn(app, @btnLoadImageButtonPushed, true);
            app.btnLoadImage.Text = 'Load Image';
            app.axesImage = uiaxes(app.UIFigure);
            title(app.axesImage, 'Input Binary Image')
            app.axesOutputImage = uiaxes(app.UIFigure);
            title(app.axesOutputImage, 'Output Image')
            app.EnterSESizeLabel = uilabel(app.UIFigure);
            app.EnterSESizeLabel.Text = 'Enter SE Size:';
            app.editSESize = uieditfield(app.UIFigure, 'numeric');
            app.btnOpenImage = uibutton(app.UIFigure, 'push');
            app.btnOpenImage.ButtonPushedFcn = createCallbackFcn(app, @btnOpenImageButtonPushed, true);
            app.btnOpenImage.Text = 'Open Image';
            app.btnCloseImage = uibutton(app.UIFigure, 'push');
            app.btnCloseImage.ButtonPushedFcn = createCallbackFcn(app, @btnCloseImageButtonPushed, true);
            app.btnCloseImage.Text = 'Close Image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SampleGUIApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end